classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19StatisticsAppUIFigure  matlab.ui.Figure
        UIAxes                        matlab.ui.control.UIAxes
        OptionButtonGroup             matlab.ui.container.ButtonGroup
        CumulativeButton              matlab.ui.control.RadioButton
        DailyButton                   matlab.ui.control.RadioButton
        DatatoPlotButtonGroup         matlab.ui.container.ButtonGroup
        CasesButton                   matlab.ui.control.RadioButton
        DeathsButton                  matlab.ui.control.RadioButton
        BothButton                    matlab.ui.control.RadioButton
        AveragedofdaysSliderLabel     matlab.ui.control.Label
        AveragedofdaysSlider          matlab.ui.control.Slider
        CountryLabel                  matlab.ui.control.Label
        CountryListBox                matlab.ui.control.ListBox
        StateorReigonListBoxLabel     matlab.ui.control.Label
        StateorReigonListBox          matlab.ui.control.ListBox
    end

    properties (Access = private)
        Days           % X axis
        AveragingWidth % Averaging width
        AverageCases   % Average Cases to be plotted
        AverageDeaths  % Average Deaths to be plotted
        CountryToPlot  % Country to plot
        Countries      % Countries array
        StateToPlot    % State to plot
        CummCases      % Cumulative Number of Cases / Y axis
        CummDeaths     % Cumulative Number of Deaths / Y axis
        DailyCases     % Daily Number of Cases / Y axis
        DailyDeaths    % Daily Number of Deaths / Y axis
    end
    methods (Access = private)
        
        function plotData(app)
            cla(app.UIAxes,"reset"); %clears the axes
            if app.CummCases
                app.ComputeAverage();
                if app.CummDeaths
                    yyaxis(app.UIAxes,"left")
                end
                bar(app.UIAxes,app.Days,app.AverageCases);
            end
            if app.CummDeaths
                app.ComputeAverage();
                if app.CummCases
                    yyaxis(app.UIAxes,"right")                    
                end
                plot(app.UIAxes,app.Days,app.AverageDeaths,'r-');
            end
            if app.DailyCases
                app.CalculateDaily();
                app.ComputeAverage();
                if app.DailyDeaths
                    yyaxis(app.UIAxes,"left")
                end
                bar(app.UIAxes,app.Days,app.AverageCases);
            end
            if app.DailyDeaths
                app.CalculateDaily();
                app.ComputeAverage();
                if app.DailyCases
                    yyaxis(app.UIAxes,"right")
                end
                plot(app.UIAxes,app.Days,app.AverageDeaths,'r-');
            end
            app.axisTitle();
            grid(app.UIAxes,"on");
        end
        
        function ComputeAverage(app)
            if app.CummCases
                app.AverageCases  = movmean(app.StateToPlot.Cases,app.AveragingWidth);
            elseif app.DailyCases
                app.AverageCases  = movmean(app.StateToPlot.DayCases,app.AveragingWidth);
            end
            if app.CummDeaths
                app.AverageDeaths = movmean(app.StateToPlot.Deaths,app.AveragingWidth);
            elseif app.DailyDeaths
                app.AverageDeaths = movmean(app.StateToPlot.DayDeaths,app.AveragingWidth);
            end
        end
        
        function CalculateDaily(app)
            if app.DailyCases
                app.StateToPlot.DayCases = zeros(size(app.StateToPlot.Cases));
                for ii = 1 : length(app.StateToPlot.Cases) - 1
                    if app.StateToPlot.Cases(ii+1) < app.StateToPlot.Cases(ii)
                        continue;
                    else
                        app.StateToPlot.DayCases(ii) = app.StateToPlot.Cases(ii+1) - app.StateToPlot.Cases(ii);
                    end
                end
            end
            if app.DailyDeaths
                app.StateToPlot.DayDeaths = zeros(size(app.StateToPlot.Deaths));
                for ii = 1 : length(app.StateToPlot.Deaths) - 1
                    if app.StateToPlot.Deaths(ii+1) < app.StateToPlot.Deaths(ii)
                        continue;
                    else
                        app.StateToPlot.DayDeaths(ii) = app.StateToPlot.Deaths(ii+1) - app.StateToPlot.Deaths(ii);
                    end
                end
            end
        end
        
        function axisTitle(app)
            % A function that puts a title to the graph
            if isequal(app.CountryToPlot.Name, "Global")
                if app.CummCases && app.CummDeaths
                    z = sprintf('Total cases and deaths (%d-days average)',app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.CummCases
                    z = sprintf('Total cases (%d-days average)',app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.CummDeaths
                    z = sprintf('Total deaths (%d-days average)',app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                end
                if app.DailyCases && app.DailyDeaths
                    z = sprintf('Daily cases and deaths (%d-days average)',app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.DailyCases
                    z = sprintf('Daily cases (%d-days average)',app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.DailyDeaths
                    z = sprintf('Daily deaths (%d-days average)',app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                end
            elseif isequal(app.StateToPlot.Name, "All")
                if app.CummCases && app.CummDeaths
                    z = sprintf('Total cases and deaths in %s (%d-days average)', app.CountryToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.CummCases
                    z = sprintf('Total cases in %s (%d-days average)', app.CountryToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.CummDeaths
                    z = sprintf('Total deaths in %s (%d-days average)', app.CountryToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                end
                if app.DailyCases && app.DailyDeaths
                    z = sprintf('Daily cases and deaths in %s (%d-days average)', app.CountryToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.DailyCases
                    z = sprintf('Daily cases in %s (%d-days average)', app.CountryToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.DailyDeaths
                    z = sprintf('Daily deaths in %s (%d-days average)', app.CountryToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                end
            else
                if app.CummCases && app.CummDeaths
                    z = sprintf('Total cases and deaths in %s (%d-days average)', app.StateToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.CummCases
                    z = sprintf('Total cases in %s (%d-days average)', app.StateToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.CummDeaths
                    z = sprintf('Total deaths in %s (%d-days average)', app.StateToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                end
                if app.DailyCases && app.DailyDeaths
                    z = sprintf('Daily cases and deaths in %s (%d-days average)', app.StateToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.DailyCases
                    z = sprintf('Daily cases in %s (%d-days average)', app.StateToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                elseif app.DailyDeaths
                    z = sprintf('Daily deaths in %s (%d-days average)', app.StateToPlot.Name, app.AveragingWidth);
                    title(app.UIAxes,z,"FontSize",15);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.Days = datetime(covid_data(1,3:end),"InputFormat","MM/dd/yyyy");
            app.AveragingWidth = app.AveragedofdaysSlider.Value;
            ii = 2;
            z = cell2mat(covid_data(2:end,3:end)); % a matrix that contains the number of cases in the odd columns and deaths in the even columns
            app.Countries = clCountry("Global",State("All",sum(z(:,1:2:end)),[],sum(z(:,1:2:end)))); % The global Country
            % Saving the data of the countries and states into The array
            % app.Countries:
            while ii < size(covid_data,1)
                % if the current country has the same name as the next
                % then it's another state in the same country
                if isequal(covid_data{ii,1},covid_data{ii+1,1})
                    x = ii + 1;
                    % storing the country in a temporary variable of
                    % clCountry type
                    temp = clCountry(covid_data{ii,1},State("All",z(ii-1,1:2:end),[],z(ii-1,2:2:end)));
                    while isequal(covid_data{ii,1},covid_data{x,1})
                        temp.States(end+1) = State(covid_data{x,2},z(x-1,1:2:end),[],z(x-1,2:2:end));
                        % adding the state to the end of states in the
                        % country
                        x = x + 1;
                    end
                    app.Countries(end+1) = temp;
                    % adding the country at the end of the countries array
                    ii = x; % updating the value of ii
                    continue; % to skip the country without adding it again
                end
                app.Countries(end+1) = clCountry(covid_data{ii,1}, State("All",z(ii-1,1:2:end),[],z(ii-1,2:2:end)));
                %here the country doesn't have any other states
                ii = ii + 1;
            end
            if ~isequal(app.Countries(end).Name,covid_data{end,1})
                app.Countries(end+1) = clCountry(covid_data{end,1}, State("All",z(end,1:2:end),[],z(end,2:2:end)));
            end
            Names = string(zeros(1,length(app.Countries)));
            for ii = 1 : length(app.Countries) 
                Names(ii) = app.Countries(ii).Name;
            end
            app.CountryListBox.Items       = Names;
            app.CountryListBox.Value       = app.CountryListBox.Items{1};
            app.CountryToPlot              = app.Countries(1);
            Names                          = string(zeros(1,length(app.CountryToPlot.States)));
            for ii = 1 : length(app.CountryToPlot.States)
                Names(ii) = app.CountryToPlot.States(ii).Name;
            end
            app.StateorReigonListBox.Items = Names;
            app.StateorReigonListBox.Value = app.StateorReigonListBox.Items{1};
            app.StateToPlot                = app.Countries(1).States(1);
            app.CummCases                  = (app.CasesButton.Value  || app.BothButton.Value) && app.CumulativeButton.Value;
            app.CummDeaths                 = (app.DeathsButton.Value || app.BothButton.Value) && app.CumulativeButton.Value;
            app.DailyCases                 = (app.CasesButton.Value  || app.BothButton.Value) && app.DailyButton.Value;
            app.DailyDeaths                = (app.DeathsButton.Value || app.BothButton.Value) && app.DailyButton.Value;
            app.plotData();
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value = app.CountryListBox.Value;
            for ii = 1 : length(app.Countries)
                if isequal(value,app.Countries(ii).Name)
                    app.CountryToPlot = app.Countries(ii);
                    break;
                end
            end
            Names = string(zeros(1,length(app.CountryToPlot.States)));
            for ii = 1 : length(app.CountryToPlot.States)
                Names(ii) = app.CountryToPlot.States(ii).Name;
            end
            app.StateorReigonListBox.Items = Names;
            app.StateorReigonListBox.Value = app.StateorReigonListBox.Items{1};
            app.StateToPlot                = app.CountryToPlot.States(1);
            app.plotData();
        end

        % Value changed function: StateorReigonListBox
        function StateorReigonListBoxValueChanged(app, event)
            value = app.StateorReigonListBox.Value;
            for ii = 1 : length(app.CountryToPlot.States)
                if isequal(value,app.CountryToPlot.States(ii).Name)
                    app.StateToPlot = app.CountryToPlot.States(ii);
                    break;
                end
            end
            app.plotData();
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.CummCases                  = (app.CasesButton.Value  || app.BothButton.Value) && app.CumulativeButton.Value;
            app.CummDeaths                 = (app.DeathsButton.Value || app.BothButton.Value) && app.CumulativeButton.Value;
            app.DailyCases                 = (app.CasesButton.Value  || app.BothButton.Value) && app.DailyButton.Value;
            app.DailyDeaths                = (app.DeathsButton.Value || app.BothButton.Value) && app.DailyButton.Value;
            app.plotData();
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.CummCases                  = (app.CasesButton.Value  || app.BothButton.Value) && app.CumulativeButton.Value;
            app.CummDeaths                 = (app.DeathsButton.Value || app.BothButton.Value) && app.CumulativeButton.Value;
            app.DailyCases                 = (app.CasesButton.Value  || app.BothButton.Value) && app.DailyButton.Value;
            app.DailyDeaths                = (app.DeathsButton.Value || app.BothButton.Value) && app.DailyButton.Value;
            app.plotData();
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.AveragedofdaysSlider.Value = round(event.Value);
            app.AveragingWidth = app.AveragedofdaysSlider.Value;
            app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19StatisticsAppUIFigure = uifigure('Visible', 'off');
            app.COVID19StatisticsAppUIFigure.Name = 'COVID-19 Statistics App';
            app.UIAxes = uiaxes(app.COVID19StatisticsAppUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.OptionButtonGroup = uibuttongroup(app.COVID19StatisticsAppUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.DatatoPlotButtonGroup = uibuttongroup(app.COVID19StatisticsAppUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.AveragedofdaysSliderLabel = uilabel(app.COVID19StatisticsAppUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.COVID19StatisticsAppUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            app.CountryLabel = uilabel(app.COVID19StatisticsAppUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.COVID19StatisticsAppUIFigure);
            app.CountryListBox.Items = {};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateorReigonListBoxLabel = uilabel(app.COVID19StatisticsAppUIFigure);
            app.StateorReigonListBoxLabel.Text = {'State or'; 'Reigon:'};
            app.StateorReigonListBox = uilistbox(app.COVID19StatisticsAppUIFigure);
            app.StateorReigonListBox.Items = {};
            app.StateorReigonListBox.ValueChangedFcn = createCallbackFcn(app, @StateorReigonListBoxValueChanged, true);
            app.StateorReigonListBox.Value = {};
            % Show the figure after all components are created
            app.COVID19StatisticsAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19StatisticsAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19StatisticsAppUIFigure)
        end
    end
end